classdef RobotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SimulationTab               matlab.ui.container.Tab
        Button                      matlab.ui.control.Button
        ESTOPBUTTONLabel            matlab.ui.control.Label
        InitialiseSimulationButton  matlab.ui.control.Button
        LEGOSORTINGSYSTEMLabel      matlab.ui.control.Label
        StateStoppedLabel           matlab.ui.control.Label
        StateLamp                   matlab.ui.control.Lamp
        OPTIONSPanel                matlab.ui.container.Panel
        YellowCheckBox              matlab.ui.control.CheckBox
        SortbycolourLabel           matlab.ui.control.Label
        RedCheckBox                 matlab.ui.control.CheckBox
        BlueCheckBox                matlab.ui.control.CheckBox
        GreenCheckBox               matlab.ui.control.CheckBox
        x4CheckBox                  matlab.ui.control.CheckBox
        SortbysizeLabel             matlab.ui.control.Label
        x2CheckBox                  matlab.ui.control.CheckBox
        x2CheckBox_2                matlab.ui.control.CheckBox
        x1CheckBox                  matlab.ui.control.CheckBox
        OPTIONSPanel_2              matlab.ui.container.Panel
        NoofbricksEditFieldLabel    matlab.ui.control.Label
        NoofbricksEditField         matlab.ui.control.NumericEditField
        TeachTab                    matlab.ui.container.Tab
        JointAnglesPanel            matlab.ui.container.Panel
        EditField_1                 matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        EditField_3                 matlab.ui.control.NumericEditField
        EditField_4                 matlab.ui.control.NumericEditField
        Joint1135135SliderLabel     matlab.ui.control.Label
        Joint1135135Slider          matlab.ui.control.Slider
        Joint2085SliderLabel        matlab.ui.control.Label
        Joint2085Slider             matlab.ui.control.Slider
        Joint31095SliderLabel       matlab.ui.control.Label
        Joint31095Slider            matlab.ui.control.Slider
        Joint49090SliderLabel       matlab.ui.control.Label
        Joint49090Slider            matlab.ui.control.Slider
        Joint58585SliderLabel       matlab.ui.control.Label
        Joint58585Slider            matlab.ui.control.Slider
        EditField_5                 matlab.ui.control.NumericEditField
        EndEffectorPanel            matlab.ui.container.Panel
        XEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        EditField_5Label            matlab.ui.control.Label
        REditField                  matlab.ui.control.NumericEditField
        EditField_6Label            matlab.ui.control.Label
        PEditField                  matlab.ui.control.NumericEditField
        EditField_7Label            matlab.ui.control.Label
        YawEditField                matlab.ui.control.NumericEditField
        RunButton_2                 matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        JogXSliderValue = 0;
        JogYSliderValue = 0;
        JogZSliderValue = 0;
        JogRSliderValue = 0;
        JogPSliderValue = 0;
        JogYawSliderValue = 0;
        
        JogJoint1Value = 0;
        JogJoint2Value = 0;
        JogJoint3Value = 0;
        JogJoint4Value = 0;
        JogJoint5Value = 0;
        JogJoint6Value = 0;
        
        sim; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, event)
            app.sim = Simulation();
        end

        % Button pushed function: InitialiseSimulationButton
        function InitialiseSimulationButtonPushed(app, event)
            app.StateStoppedLabel.Text = 'State: Start';
            app.sim.init();
            app.NoofbricksEditField.Value = app.sim.brickNo; 
        end

        % Value changed function: Joint1135135Slider
        function Joint1135135SliderValueChanged(app, event)
            value = app.Joint1135135Slider.Value;
            app.EditField_1.Value = value;
        end

        % Value changed function: Joint2085Slider
        function Joint2085SliderValueChanged(app, event)
            value = app.Joint2085Slider.Value;
            app.EditField_2.Value = value;            
        end

        % Value changed function: Joint31095Slider
        function Joint31095SliderValueChanged(app, event)
            value = app.Joint31095Slider.Value;
            app.EditField_3.Value = value;           
        end

        % Value changed function: Joint49090Slider
        function Joint49090SliderValueChanged(app, event)
            value = app.Joint49090Slider.Value;
            app.EditField_4.Value = value;            
        end

        % Value changed function: Joint58585Slider
        function Joint58585SliderValueChanged(app, event)
            value = app.Joint58585Slider.Value;
            app.EditField_5.Value = value;        
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Q = [app.EditField_1.Value app.EditField_2.Value app.EditField_3.Value app.EditField_4.Value app.EditField_5.Value]
            Qdeg2rad = deg2rad(Q);
            app.sim.robot.moveJoints(Qdeg2rad);
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            value = app.EditField_1.Value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            Value = app.NoofbricksEditField.Value;
            if (Value >= 1 && Value <= 10)
                app.sim.brickNo = app.NoofbricksEditField.Value;
                disp(app.sim.brickNo);
            else
                disp("Invalid number of bricks, pick a number between 1 and 10")
                disp(app.sim.brickNo);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Button = uibutton(app.SimulationTab, 'push');
            app.Button.Icon = 'estop.jpeg';
            app.Button.Text = {''; ''};
            app.ESTOPBUTTONLabel = uilabel(app.SimulationTab);
            app.ESTOPBUTTONLabel.Text = 'ESTOP BUTTON';
            app.InitialiseSimulationButton = uibutton(app.SimulationTab, 'push');
            app.InitialiseSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseSimulationButtonPushed, true);
            app.InitialiseSimulationButton.Text = 'Initialise Simulation';
            app.LEGOSORTINGSYSTEMLabel = uilabel(app.SimulationTab);
            app.LEGOSORTINGSYSTEMLabel.Text = 'LEGO SORTING SYSTEM';
            app.StateStoppedLabel = uilabel(app.SimulationTab);
            app.StateStoppedLabel.Text = 'State: Stopped';
            app.StateLamp = uilamp(app.SimulationTab);
            app.OPTIONSPanel = uipanel(app.SimulationTab);
            app.OPTIONSPanel.Title = 'OPTIONS';
            app.YellowCheckBox = uicheckbox(app.OPTIONSPanel);
            app.YellowCheckBox.Text = 'Yellow';
            app.SortbycolourLabel = uilabel(app.OPTIONSPanel);
            app.SortbycolourLabel.Text = 'Sort by colour: ';
            app.RedCheckBox = uicheckbox(app.OPTIONSPanel);
            app.RedCheckBox.Text = 'Red';
            app.BlueCheckBox = uicheckbox(app.OPTIONSPanel);
            app.BlueCheckBox.Text = 'Blue';
            app.GreenCheckBox = uicheckbox(app.OPTIONSPanel);
            app.GreenCheckBox.Text = 'Green';
            app.x4CheckBox = uicheckbox(app.OPTIONSPanel);
            app.x4CheckBox.Text = '2x4';
            app.SortbysizeLabel = uilabel(app.OPTIONSPanel);
            app.SortbysizeLabel.Text = 'Sort by size: ';
            app.x2CheckBox = uicheckbox(app.OPTIONSPanel);
            app.x2CheckBox.Text = '2x2';
            app.x2CheckBox_2 = uicheckbox(app.OPTIONSPanel);
            app.x2CheckBox_2.Text = '1x2';
            app.x1CheckBox = uicheckbox(app.OPTIONSPanel);
            app.x1CheckBox.Text = '1x1';
            app.OPTIONSPanel_2 = uipanel(app.SimulationTab);
            app.OPTIONSPanel_2.Title = 'OPTIONS';
            app.NoofbricksEditFieldLabel = uilabel(app.OPTIONSPanel_2);
            app.NoofbricksEditFieldLabel.Text = 'No. of bricks';
            app.NoofbricksEditField = uieditfield(app.OPTIONSPanel_2, 'numeric');
            app.NoofbricksEditField.Limits = [1 10];
            app.NoofbricksEditField.Value = 2;
            app.TeachTab = uitab(app.TabGroup);
            app.TeachTab.Title = 'Teach';
            app.JointAnglesPanel = uipanel(app.TeachTab);
            app.JointAnglesPanel.Title = 'Joint Angles';
            app.EditField_1 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_1.Limits = [-135 135];
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_2 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_2.Limits = [0 85];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_3.Limits = [-10 95];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_4.Limits = [-90 90];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Joint1135135SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint1135135SliderLabel.Text = {'Joint 1'; '(-135-135)'; ''};
            app.Joint1135135Slider = uislider(app.JointAnglesPanel);
            app.Joint1135135Slider.Limits = [-135 135];
            app.Joint1135135Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1135135SliderValueChanged, true);
            app.Joint2085SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint2085SliderLabel.Text = {'Joint 2'; '(0-85)'; ''};
            app.Joint2085Slider = uislider(app.JointAnglesPanel);
            app.Joint2085Slider.Limits = [0 85];
            app.Joint2085Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2085SliderValueChanged, true);
            app.Joint31095SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint31095SliderLabel.Text = {'Joint 3'; '(-10-95)'};
            app.Joint31095Slider = uislider(app.JointAnglesPanel);
            app.Joint31095Slider.Limits = [-10 95];
            app.Joint31095Slider.ValueChangedFcn = createCallbackFcn(app, @Joint31095SliderValueChanged, true);
            app.Joint49090SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint49090SliderLabel.Text = {'Joint 4'; '(-90-90)'; ''};
            app.Joint49090Slider = uislider(app.JointAnglesPanel);
            app.Joint49090Slider.Limits = [-90 90];
            app.Joint49090Slider.ValueChangedFcn = createCallbackFcn(app, @Joint49090SliderValueChanged, true);
            app.Joint58585SliderLabel = uilabel(app.JointAnglesPanel);
            app.Joint58585SliderLabel.Text = {'Joint 5'; '(-85-85)'; ''};
            app.Joint58585Slider = uislider(app.JointAnglesPanel);
            app.Joint58585Slider.Limits = [-85 85];
            app.Joint58585Slider.ValueChangedFcn = createCallbackFcn(app, @Joint58585SliderValueChanged, true);
            app.EditField_5 = uieditfield(app.JointAnglesPanel, 'numeric');
            app.EditField_5.Limits = [-85 85];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EndEffectorPanel = uipanel(app.TeachTab);
            app.EndEffectorPanel.Title = 'End-Effector';
            app.XEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.ZEditField.Editable = 'off';
            app.EditField_5Label = uilabel(app.EndEffectorPanel);
            app.EditField_5Label.Text = 'φ';
            app.REditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.REditField.Editable = 'off';
            app.EditField_6Label = uilabel(app.EndEffectorPanel);
            app.EditField_6Label.Text = 'θ';
            app.PEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.PEditField.Editable = 'off';
            app.EditField_7Label = uilabel(app.EndEffectorPanel);
            app.EditField_7Label.Text = 'ψ';
            app.YawEditField = uieditfield(app.EndEffectorPanel, 'numeric');
            app.YawEditField.Editable = 'off';
            app.RunButton_2 = uibutton(app.TeachTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.RunButton = uibutton(app.TeachTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end